classdef gui_input_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputParametersUIFigure         matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        BodyTab                         matlab.ui.container.Tab
        FrontSuspensionPanel            matlab.ui.container.Panel
        UnsprungMassEditFieldLabel      matlab.ui.control.Label
        UnsprungMassEditField           matlab.ui.control.NumericEditField
        DampingEditFieldLabel           matlab.ui.control.Label
        DampingEditField                matlab.ui.control.NumericEditField
        StiffnessEditFieldLabel         matlab.ui.control.Label
        StiffnessEditField              matlab.ui.control.NumericEditField
        SprungMassPanel                 matlab.ui.container.Panel
        ChasisIDEditFieldLabel          matlab.ui.control.Label
        ChasisIDEditField               matlab.ui.control.EditField
        SprungMassEditFieldLabel        matlab.ui.control.Label
        SprungMassEditField             matlab.ui.control.NumericEditField
        DefineCoordinateButton          matlab.ui.control.Button
        I_xxEditFieldLabel              matlab.ui.control.Label
        I_xxEditField                   matlab.ui.control.NumericEditField
        I_yyEditFieldLabel              matlab.ui.control.Label
        I_yyEditField                   matlab.ui.control.NumericEditField
        WheelPositionButton             matlab.ui.control.Button
        RearSuspensionPanel             matlab.ui.container.Panel
        UnsprungMassEditField_2Label    matlab.ui.control.Label
        UnsprungMassEditField_2         matlab.ui.control.NumericEditField
        DampingEditField_2Label         matlab.ui.control.Label
        DampingEditField_2              matlab.ui.control.NumericEditField
        StiffnessEditField_2Label       matlab.ui.control.Label
        StiffnessEditField_2            matlab.ui.control.NumericEditField
        TirePanel                       matlab.ui.container.Panel
        ModelEditFieldLabel             matlab.ui.control.Label
        ModelEditField                  matlab.ui.control.EditField
        StiffnessEditField_3Label       matlab.ui.control.Label
        StiffnessEditField_3            matlab.ui.control.NumericEditField
        EngineTab                       matlab.ui.container.Tab
        EngineNameEditFieldLabel        matlab.ui.control.Label
        EngineNameEditField             matlab.ui.control.EditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        SetinertiaButton                matlab.ui.control.Button
        Inertiamatrixkgm2EditFieldLabel  matlab.ui.control.Label
        Inertiamatrixkgm2EditField      matlab.ui.control.NumericEditField
        IdlespeedEditFieldLabel         matlab.ui.control.Label
        IdlespeedEditField              matlab.ui.control.NumericEditField
        TorqueIdlespeedEditField_2Label  matlab.ui.control.Label
        TorqueIdlespeedEditField_2      matlab.ui.control.NumericEditField
        EngineModelEditFieldLabel       matlab.ui.control.Label
        EngineModelEditField            matlab.ui.control.EditField
        EnginemountsTab                 matlab.ui.container.Tab
        ConfigurationDropDownLabel      matlab.ui.control.Label
        ConfigurationDropDown           matlab.ui.control.DropDown
        LocationOrientaionButton        matlab.ui.control.Button
        StiffnessDampingButton          matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        GotoOptimizationProccessButton  matlab.ui.control.Button
        InputParametersLabel            matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        InputNameEditFieldLabel         matlab.ui.control.Label
        InputNameEditField              matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('gui_curr.mat');
            
            if strcmp(gui_curr.input_name , 'not selected')==0
                load(['SavedResults/',gui_curr.input_name]);
                app.InputNameEditField.Value = gui_curr.input_name;
                app.InputNameEditField.Editable = 'off';
%                 sus.ChassisID = ChasisIDEditField.Value;
            app.I_xxEditField.Value = sus.Isxx;
            app.I_yyEditField.Value = sus.Isyy;
            app.SprungMassEditField.Value = sus.Ms;
            app.UnsprungMassEditField.Value = sus.Mu1;%front
            app.UnsprungMassEditField.Value = sus.Mu4;%front
            app.UnsprungMassEditField_2.Value = sus.Mu3;%rear
            app.UnsprungMassEditField_2.Value = sus.Mu2;%rear
            app.StiffnessEditField.Value = sus.ks1;
            app.StiffnessEditField.Value = sus.ks4;
            app.StiffnessEditField_2.Value = sus.ks3;
            app.StiffnessEditField_2.Value = sus.ks2;
            app.DampingEditField.Value = sus.cs1;
            app.DampingEditField.Value = sus.cs4;
            app.DampingEditField_2.Value = sus.cs3;
            app.DampingEditField_2.Value = sus.cs2;
            app.StiffnessEditField_3.Value = sus.kt1;
            app.StiffnessEditField_3.Value = sus.kt2;
            app.StiffnessEditField_3.Value = sus.kt3;
            app.StiffnessEditField_3.Value = sus.kt4;
            
%             app.EngineNameEditField.Value = eng.name;
%             app.EngineModelEditField.Value = eng.model;
            app.MasskgEditField.Value = eng.m;
            load('temp.mat');temp.inertia = eng.I;save('temp.mat','temp');
            app.IdlespeedEditField.Value = eng.idle_speed;
            app.TorqueIdlespeedEditField_2.Value = eng.max_torque;
                
            else
                app.InputNameEditField.Value = 'Enter name';
                app.InputNameEditField.Editable = 'on';
            end
        end

        % Button pushed function: LocationOrientaionButton
        function LocationOrientaionButtonPushed(app, event)
            gui_loc_orie
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            load('gui_curr.mat');
            if strcmp(gui_curr.input_name , 'not selected')==0
                load(['SavedResults/',gui_curr.input_name]);
            else
               
               load(['SavedResults/','empty.mat']);
 
            end
            sus.ChassisID = app.ChasisIDEditField.Value;
            sus.Isxx = app.I_xxEditField.Value;
            sus.Isyy = app.I_yyEditField.Value;
            sus.Ms = app.SprungMassEditField.Value;
            sus.Mu1 = app.UnsprungMassEditField.Value;%front
            sus.Mu4 = app.UnsprungMassEditField.Value;%front
            sus.Mu3 = app.UnsprungMassEditField_2.Value;%rear
            sus.Mu2 = app.UnsprungMassEditField_2.Value;%rear
            sus.ks1 = app.StiffnessEditField.Value;
            sus.ks4 = app.StiffnessEditField.Value;
            sus.ks3 = app.StiffnessEditField_2.Value;
            sus.ks2 = app.StiffnessEditField_2.Value;
            sus.cs1 = app.DampingEditField.Value;
            sus.cs4 = app.DampingEditField.Value;
            sus.cs3 = app.DampingEditField_2.Value;
            sus.cs2 = app.DampingEditField_2.Value;
            sus.kt1 = app.StiffnessEditField_3.Value;
            sus.kt2 = app.StiffnessEditField_3.Value;
            sus.kt3 = app.StiffnessEditField_3.Value;
            sus.kt4 = app.StiffnessEditField_3.Value;
            
            eng.name = app.EngineNameEditField.Value;
            eng.model = app.EngineModelEditField.Value;
            eng.mass = app.MasskgEditField.Value;
            load('temp.mat');eng.I = temp.inertia;
            eng.idle_speed = app.IdlespeedEditField.Value;
            eng.max_torque = app.TorqueIdlespeedEditField_2.Value;
            save(['SavedResults/',gui_curr.input_name],'sus','eng');
            
            
        end

        % Callback function
        function SetInertiaButtonPushed(app, event)
            gui_eng_inertia
        end

        % Button pushed function: SetinertiaButton
        function SetinertiaButtonPushed(app, event)
            gui_eng_inertia
            
        end

        % Button pushed function: StiffnessDampingButton
        function StiffnessDampingButtonPushed(app, event)
            gui_stiffness
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputParametersUIFigure = uifigure;
            app.InputParametersUIFigure.Name = 'Input Parameters';
            app.TabGroup2 = uitabgroup(app.InputParametersUIFigure);
            app.BodyTab = uitab(app.TabGroup2);
            app.BodyTab.Title = 'Body';
            app.FrontSuspensionPanel = uipanel(app.BodyTab);
            app.FrontSuspensionPanel.Title = 'Front Suspension';
            app.UnsprungMassEditFieldLabel = uilabel(app.FrontSuspensionPanel);
            app.UnsprungMassEditFieldLabel.Text = 'Unsprung Mass';
            app.UnsprungMassEditField = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.DampingEditFieldLabel = uilabel(app.FrontSuspensionPanel);
            app.DampingEditFieldLabel.Text = 'Damping';
            app.DampingEditField = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.StiffnessEditFieldLabel = uilabel(app.FrontSuspensionPanel);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.SprungMassPanel = uipanel(app.BodyTab);
            app.SprungMassPanel.Title = 'Sprung Mass';
            app.ChasisIDEditFieldLabel = uilabel(app.SprungMassPanel);
            app.ChasisIDEditFieldLabel.Text = 'ChasisID';
            app.ChasisIDEditField = uieditfield(app.SprungMassPanel, 'text');
            app.ChasisIDEditField.Value = '123456';
            app.SprungMassEditFieldLabel = uilabel(app.SprungMassPanel);
            app.SprungMassEditFieldLabel.Text = 'Sprung Mass';
            app.SprungMassEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.DefineCoordinateButton = uibutton(app.SprungMassPanel, 'push');
            app.DefineCoordinateButton.Text = 'Define Coordinate';
            app.I_xxEditFieldLabel = uilabel(app.SprungMassPanel);
            app.I_xxEditFieldLabel.Text = 'I_xx';
            app.I_xxEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.I_yyEditFieldLabel = uilabel(app.SprungMassPanel);
            app.I_yyEditFieldLabel.Text = 'I_yy';
            app.I_yyEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.WheelPositionButton = uibutton(app.SprungMassPanel, 'push');
            app.WheelPositionButton.Text = 'Wheel Position';
            app.RearSuspensionPanel = uipanel(app.BodyTab);
            app.RearSuspensionPanel.Title = 'Rear Suspension';
            app.UnsprungMassEditField_2Label = uilabel(app.RearSuspensionPanel);
            app.UnsprungMassEditField_2Label.Text = 'Unsprung Mass';
            app.UnsprungMassEditField_2 = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.DampingEditField_2Label = uilabel(app.RearSuspensionPanel);
            app.DampingEditField_2Label.Text = 'Damping';
            app.DampingEditField_2 = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.StiffnessEditField_2Label = uilabel(app.RearSuspensionPanel);
            app.StiffnessEditField_2Label.Text = 'Stiffness';
            app.StiffnessEditField_2 = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.TirePanel = uipanel(app.BodyTab);
            app.TirePanel.Title = 'Tire';
            app.ModelEditFieldLabel = uilabel(app.TirePanel);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.TirePanel, 'text');
            app.StiffnessEditField_3Label = uilabel(app.TirePanel);
            app.StiffnessEditField_3Label.Text = 'Stiffness';
            app.StiffnessEditField_3 = uieditfield(app.TirePanel, 'numeric');
            app.EngineTab = uitab(app.TabGroup2);
            app.EngineTab.Title = 'Engine';
            app.EngineNameEditFieldLabel = uilabel(app.EngineTab);
            app.EngineNameEditFieldLabel.Text = 'Engine Name';
            app.EngineNameEditField = uieditfield(app.EngineTab, 'text');
            app.EngineNameEditField.Value = 'saipa_pride';
            app.MasskgEditFieldLabel = uilabel(app.EngineTab);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.EngineTab, 'numeric');
            app.SetinertiaButton = uibutton(app.EngineTab, 'push');
            app.SetinertiaButton.ButtonPushedFcn = createCallbackFcn(app, @SetinertiaButtonPushed, true);
            app.SetinertiaButton.Text = 'Set inertia ...';
            app.Inertiamatrixkgm2EditFieldLabel = uilabel(app.EngineTab);
            app.Inertiamatrixkgm2EditFieldLabel.Text = 'Inertia matrix (kg.m^2)';
            app.Inertiamatrixkgm2EditField = uieditfield(app.EngineTab, 'numeric');
            app.IdlespeedEditFieldLabel = uilabel(app.EngineTab);
            app.IdlespeedEditFieldLabel.Text = 'Idle speed';
            app.IdlespeedEditField = uieditfield(app.EngineTab, 'numeric');
            app.TorqueIdlespeedEditField_2Label = uilabel(app.EngineTab);
            app.TorqueIdlespeedEditField_2Label.Text = 'Torque @ Idle speed';
            app.TorqueIdlespeedEditField_2 = uieditfield(app.EngineTab, 'numeric');
            app.EngineModelEditFieldLabel = uilabel(app.EngineTab);
            app.EngineModelEditFieldLabel.Text = 'Engine Model';
            app.EngineModelEditField = uieditfield(app.EngineTab, 'text');
            app.EngineModelEditField.Value = 'EF7';
            app.EnginemountsTab = uitab(app.TabGroup2);
            app.EnginemountsTab.Title = 'Engine mounts';
            app.ConfigurationDropDownLabel = uilabel(app.EnginemountsTab);
            app.ConfigurationDropDownLabel.Text = 'Configuration';
            app.ConfigurationDropDown = uidropdown(app.EnginemountsTab);
            app.ConfigurationDropDown.Items = {'3 Elastomeric', '1 Hydraulic-2 Elastomeric'};
            app.ConfigurationDropDown.Value = '3 Elastomeric';
            app.LocationOrientaionButton = uibutton(app.EnginemountsTab, 'push');
            app.LocationOrientaionButton.ButtonPushedFcn = createCallbackFcn(app, @LocationOrientaionButtonPushed, true);
            app.LocationOrientaionButton.Text = 'Location & Orientaion';
            app.StiffnessDampingButton = uibutton(app.EnginemountsTab, 'push');
            app.StiffnessDampingButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessDampingButtonPushed, true);
            app.StiffnessDampingButton.Text = 'Stiffness & Damping';
            app.SaveButton = uibutton(app.InputParametersUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GotoOptimizationProccessButton = uibutton(app.InputParametersUIFigure, 'push');
            app.GotoOptimizationProccessButton.Text = {'Goto '; 'Optimization Proccess'};
            app.InputParametersLabel = uilabel(app.InputParametersUIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.CloseButton = uibutton(app.InputParametersUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InputNameEditFieldLabel = uilabel(app.InputParametersUIFigure);
            app.InputNameEditFieldLabel.Text = 'Input Name';
            app.InputNameEditField = uieditfield(app.InputParametersUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_input_parameters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputParametersUIFigure)
        end
    end
end