classdef gui_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EngineMountOptimizationToolboxUIFigure  matlab.ui.Figure
        EngineMountOptimizationToolboxLabel  matlab.ui.control.Label
        byNVHteamLabel                matlab.ui.control.Label
        SharifUniversityofTechnologyLabel  matlab.ui.control.Label
        Step1SetinputparametersPanel  matlab.ui.container.Panel
        EditButton_2                  matlab.ui.control.Button
        ChooseFileDropDownLabel       matlab.ui.control.Label
        ChooseFileDropDown            matlab.ui.control.DropDown
        CreateButton                  matlab.ui.control.Button
        Step3ViewResultsPanel         matlab.ui.container.Panel
        ViewResultsButton             matlab.ui.control.Button
        ChooseFileDropDown_3Label     matlab.ui.control.Label
        ChooseFileDropDown_3          matlab.ui.control.DropDown
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Step2OptimizationPanel        matlab.ui.container.Panel
        EditButton_3                  matlab.ui.control.Button
        ChooseFileDropDown_2Label     matlab.ui.control.Label
        ChooseFileDropDown_2          matlab.ui.control.DropDown
        CreateButton_2                matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        openlogfileButton             matlab.ui.control.Button
    end


    properties (Access = public)
        
        app_main
        item_input = 'not selected' % Description
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             img = imread('splash.jpg');  %# A sample image to display
            %             jimg = im2java(img);
            %             frame = javax.swing.JFrame;
            %             frame.setUndecorated(true);
            %             icon = javax.swing.ImageIcon(jimg);
            %             label = javax.swing.JLabel(icon);
            %             frame.getContentPane.add(label);
            %             frame.pack;
            %             screenSize = get(0,'ScreenSize');  %# Get the screen size from the root object
            %             %             frame.setSize(screenSize(3)/2,screenSize(4)/2);
            %             frame.setLocation(screenSize(3)/2-300,screenSize(4)/2-200);
            %             frame.show;
            %             pause(3);
            %             frame.hide
            RefreshButtonPushed(app)
            load(['SavedResults/','gui_def.mat']);
            gui_curr = gui_def;
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            RefreshButtonPushed(app)
            
            
        end

        % Value changed function: ChooseFileDropDown
        function ChooseFileDropDownValueChanged(app, event)
            value_inp = app.ChooseFileDropDown.Value;
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.input_name = value_inp;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
        end

        % Value changed function: ChooseFileDropDown_2
        function ChooseFileDropDown_2ValueChanged(app, event)
            value_inp = app.ChooseFileDropDown_2.Value;
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.opt_name = value_inp;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            load(['SavedResults/','user_new_inp.mat']);
            
            %             gui_curr.input_name = [app.NewEditField.Value,'_inp.mat'];
            gui_curr.input_name = 'user_new_inp.mat';             
            eval([gui_curr.input_name(1:end-4),'=user_new_inp;']);
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            % %             save(['SavedResults/',gui_curr.input_name],gui_curr.input_name(1:end-4));
            
            
            gui_input_parameters;
        end

        % Button pushed function: CreateButton_2
        function CreateButton_2Pushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            load(['SavedResults/','user_new_opt.mat']);
            
            %             gui_curr.opt_name = [app.NewEditField_2.Value,'_opt.mat'];
            gui_curr.opt_name = 'user_new_opt.mat';
            eval([gui_curr.opt_name(1:end-4),'=user_new_opt;']);
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            %             save(['SavedResults/',gui_curr.opt_name],gui_curr.opt_name(1:end-4));
            if strcmp(gui_curr.input_name,'not selected')==0           
            gui_optimization;
            end
        end

        % Button pushed function: EditButton_3
        function EditButton_3Pushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.opt_name = app.ChooseFileDropDown_2.Value;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            strcmp(gui_curr.opt_name,'not selected')
            if strcmp(gui_curr.opt_name,'not selected')==0 && strcmp(gui_curr.input_name,'not selected')==0
                gui_optimization;
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: EditButton_2
        function GoStep1ButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.input_name = app.ChooseFileDropDown.Value;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            if strcmp(gui_curr.input_name,'not selected')==0
                gui_input_parameters;
            end
        end

        % Callback function
        function GoStep2ButtonPushed(app, event)
            gui_optimization;
        end

        % Button pushed function: ViewResultsButton
        function GoStep3ButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            value = app.ChooseFileDropDown_3.Value;
            if strcmp(value,'not selected')==0
                gui_results;
            end
            
        end

        % Callback function
        function LoadButton_2Pushed(app, event)
            gui_optinput
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            
            
            mat_files=dir('SavedResults/*_inp.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.ChooseFileDropDown.Items(i+1)={mat_files(i).name};
                
            end
            app.ChooseFileDropDown.Value=app.ChooseFileDropDown.Items(1);
            
            mat_files=dir('SavedResults/*_opt.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.ChooseFileDropDown_2.Items(i+1)={mat_files(i).name};
                
            end
            app.ChooseFileDropDown_2.Value=app.ChooseFileDropDown_2.Items(1);
            
            mat_files=dir('SavedResults/*_res.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.ChooseFileDropDown_3.Items(i+1)={mat_files(i).name};
                
            end
            app.ChooseFileDropDown_3.Value=app.ChooseFileDropDown_3.Items(1);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            prompt = {sprintf('Enter the result name to be saved :')};
            dlg_title = 'Result Name';
            num_lines = 1;
             load(['SavedResults/','gui_curr.mat']);
            defaultans = {gui_curr.opt_name(1:end-8)};
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            gui_curr.res_name = [char(answer),'_res.mat'];
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            gui_Run_Me;
        end

        % Callback function
        function Step1EditButton_2Pushed(app, event)
            gui_input_parameters
        end

        % Button pushed function: openlogfileButton
        function openlogfileButtonPushed(app, event)
            winopen('log.txt');
        end

        % Callback function
        function step1LoadButtonPushed(app, event)
            
            gui_loadinput;
            %             disp(app.item_input)
            %             app.NameEditField.Value=app.item_input;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EngineMountOptimizationToolboxUIFigure = uifigure;
            app.EngineMountOptimizationToolboxUIFigure.Name = 'Engine Mount Optimization Toolbox';
            app.EngineMountOptimizationToolboxLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.EngineMountOptimizationToolboxLabel.Text = 'Engine Mount Optimization Toolbox';
            app.byNVHteamLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.byNVHteamLabel.Text = 'by NVH team';
            app.SharifUniversityofTechnologyLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.SharifUniversityofTechnologyLabel.Text = 'Sharif University of Technology';
            app.Step1SetinputparametersPanel = uipanel(app.EngineMountOptimizationToolboxUIFigure);
            app.Step1SetinputparametersPanel.Title = 'Step 1 : Set input parameters';
            app.EditButton_2 = uibutton(app.Step1SetinputparametersPanel, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoStep1ButtonPushed, true);
            app.EditButton_2.Text = 'Edit';
            app.ChooseFileDropDownLabel = uilabel(app.Step1SetinputparametersPanel);
            app.ChooseFileDropDownLabel.Text = 'Choose File:';
            app.ChooseFileDropDown = uidropdown(app.Step1SetinputparametersPanel);
            app.ChooseFileDropDown.Items = {'not selected'};
            app.ChooseFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseFileDropDownValueChanged, true);
            app.ChooseFileDropDown.Value = 'not selected';
            app.CreateButton = uibutton(app.Step1SetinputparametersPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.Step3ViewResultsPanel = uipanel(app.EngineMountOptimizationToolboxUIFigure);
            app.Step3ViewResultsPanel.Title = 'Step 3 : View Results';
            app.ViewResultsButton = uibutton(app.Step3ViewResultsPanel, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GoStep3ButtonPushed, true);
            app.ViewResultsButton.Text = 'View Results';
            app.ChooseFileDropDown_3Label = uilabel(app.Step3ViewResultsPanel);
            app.ChooseFileDropDown_3Label.Text = 'Choose File:';
            app.ChooseFileDropDown_3 = uidropdown(app.Step3ViewResultsPanel);
            app.ChooseFileDropDown_3.Items = {'not selected'};
            app.ChooseFileDropDown_3.Value = 'not selected';
            app.Button = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.Button.Icon = 'SHARIFlogo.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.Button_2.Icon = 'SAIPAlogo.png';
            app.Button_2.Text = '';
            app.Step2OptimizationPanel = uipanel(app.EngineMountOptimizationToolboxUIFigure);
            app.Step2OptimizationPanel.Title = 'Step 2 : Optimization';
            app.EditButton_3 = uibutton(app.Step2OptimizationPanel, 'push');
            app.EditButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditButton_3Pushed, true);
            app.EditButton_3.Text = 'Edit';
            app.ChooseFileDropDown_2Label = uilabel(app.Step2OptimizationPanel);
            app.ChooseFileDropDown_2Label.Text = 'Choose File:';
            app.ChooseFileDropDown_2 = uidropdown(app.Step2OptimizationPanel);
            app.ChooseFileDropDown_2.Items = {'not selected'};
            app.ChooseFileDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChooseFileDropDown_2ValueChanged, true);
            app.ChooseFileDropDown_2.Value = 'not selected';
            app.CreateButton_2 = uibutton(app.Step2OptimizationPanel, 'push');
            app.CreateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreateButton_2Pushed, true);
            app.CreateButton_2.Text = 'Create';
            app.STARTButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Icon = 'start.png';
            app.STARTButton.Text = 'START';
            app.RefreshButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'refresh.png';
            app.RefreshButton.Text = 'Refresh';
            app.ExitButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'close.png';
            app.ExitButton.Text = 'Exit';
            app.openlogfileButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.openlogfileButton.ButtonPushedFcn = createCallbackFcn(app, @openlogfileButtonPushed, true);
            app.openlogfileButton.Text = 'open log file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EngineMountOptimizationToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EngineMountOptimizationToolboxUIFigure)
        end
    end
end