classdef gui_optimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StrategyTab                     matlab.ui.container.Tab
        Stage1Panel                     matlab.ui.container.Panel
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        SettingsButton                  matlab.ui.control.Button
        EnableCheckBox_3                matlab.ui.control.CheckBox
        Best5CheckBox                   matlab.ui.control.CheckBox
        Stage2Panel                     matlab.ui.container.Panel
        MethodDropDown_2Label           matlab.ui.control.Label
        MethodDropDown_2                matlab.ui.control.DropDown
        SettingsButton_2                matlab.ui.control.Button
        EnableCheckBox                  matlab.ui.control.CheckBox
        Best5CheckBox_2                 matlab.ui.control.CheckBox
        Stage3Panel                     matlab.ui.container.Panel
        MethodDropDown_3Label           matlab.ui.control.Label
        MethodDropDown_3                matlab.ui.control.DropDown
        SettingsButton_3                matlab.ui.control.Button
        EnableCheckBox_2                matlab.ui.control.CheckBox
        Best5CheckBox_3                 matlab.ui.control.CheckBox
        ConstraintsTab_2                matlab.ui.container.Tab
        ForcePanel                      matlab.ui.container.Panel
        CriteriaButtonGroup             matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton      matlab.ui.control.RadioButton
        MinimizesummationofForcesButton  matlab.ui.control.RadioButton
        SelectedForcesPanel             matlab.ui.container.Panel
        Mount1Panel                     matlab.ui.container.Panel
        LocalXCheckBox                  matlab.ui.control.CheckBox
        LocalYCheckBox                  matlab.ui.control.CheckBox
        LocalZCheckBox                  matlab.ui.control.CheckBox
        MagnitudeCheckBox               matlab.ui.control.CheckBox
        Mount2Panel                     matlab.ui.container.Panel
        LocalXCheckBox_2                matlab.ui.control.CheckBox
        LocalYCheckBox_2                matlab.ui.control.CheckBox
        LocalZCheckBox_2                matlab.ui.control.CheckBox
        MagnitudeCheckBox_2             matlab.ui.control.CheckBox
        Mount3Panel                     matlab.ui.container.Panel
        LocalXCheckBox_3                matlab.ui.control.CheckBox
        LocalYCheckBox_3                matlab.ui.control.CheckBox
        LocalZCheckBox_3                matlab.ui.control.CheckBox
        MagnitudeCheckBox_3             matlab.ui.control.CheckBox
        StandardTestsPanel              matlab.ui.container.Panel
        KEDcriteriaCheckBox             matlab.ui.control.CheckBox
        percentageEditFieldLabel        matlab.ui.control.Label
        percentageEditField             matlab.ui.control.NumericEditField
        UpperandLowerBoundsPanel        matlab.ui.container.Panel
        MountsLocationOrientationButton  matlab.ui.control.Button
        StaticDeflectionsButton         matlab.ui.control.Button
        NaturalfrequenciesButton        matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        AddButton                       matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        AvailableMethodsListBoxLabel    matlab.ui.control.Label
        AvailableMethodsListBox         matlab.ui.control.ListBox
        SelectedMethodsListBoxLabel     matlab.ui.control.Label
        SelectedMethodsListBox          matlab.ui.control.ListBox
        CloseButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        ViewResultsButton               matlab.ui.control.Button
        OptimizationLabel_2             matlab.ui.control.Label
        OptimizationFileEditFieldLabel  matlab.ui.control.Label
        OptimizationFileEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                load('gui_curr.mat');
                gui_curr.opt_name
                load(['SavedResults/',gui_curr.opt_name]);
                app.OptimizationFileEditField.Value = gui_curr.opt_name;
                app.OptimizationFileEditField.Editable = 'off';
                save('SavedResults/temp_optt.mat')
        end

        % Button pushed function: SettingsButton
        function TRASettingsButtonPushed(app, event)
            gui_TRAoption
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationUIFigure = uifigure;
            app.OptimizationUIFigure.Name = 'Optimization';
            app.TabGroup = uitabgroup(app.OptimizationUIFigure);
            app.StrategyTab = uitab(app.TabGroup);
            app.StrategyTab.Title = 'Strategy';
            app.Stage1Panel = uipanel(app.StrategyTab);
            app.Stage1Panel.Title = 'Stage 1';
            app.MethodDropDownLabel = uilabel(app.Stage1Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Stage1Panel);
            app.MethodDropDown.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.MethodDropDown.Value = 'TRA';
            app.SettingsButton = uibutton(app.Stage1Panel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @TRASettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.EnableCheckBox_3 = uicheckbox(app.Stage1Panel);
            app.EnableCheckBox_3.Enable = 'off';
            app.EnableCheckBox_3.Text = 'Enable';
            app.EnableCheckBox_3.Value = true;
            app.Best5CheckBox = uicheckbox(app.Stage1Panel);
            app.Best5CheckBox.Text = 'Best 5';
            app.Stage2Panel = uipanel(app.StrategyTab);
            app.Stage2Panel.Title = 'Stage 2';
            app.MethodDropDown_2Label = uilabel(app.Stage2Panel);
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodDropDown_2 = uidropdown(app.Stage2Panel);
            app.MethodDropDown_2.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.MethodDropDown_2.Value = 'TF';
            app.SettingsButton_2 = uibutton(app.Stage2Panel, 'push');
            app.SettingsButton_2.Text = 'Settings';
            app.EnableCheckBox = uicheckbox(app.Stage2Panel);
            app.EnableCheckBox.Text = 'Enable';
            app.Best5CheckBox_2 = uicheckbox(app.Stage2Panel);
            app.Best5CheckBox_2.Text = 'Best 5';
            app.Stage3Panel = uipanel(app.StrategyTab);
            app.Stage3Panel.Title = 'Stage 3';
            app.MethodDropDown_3Label = uilabel(app.Stage3Panel);
            app.MethodDropDown_3Label.Text = 'Method';
            app.MethodDropDown_3 = uidropdown(app.Stage3Panel);
            app.MethodDropDown_3.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.MethodDropDown_3.Value = 'TA';
            app.SettingsButton_3 = uibutton(app.Stage3Panel, 'push');
            app.SettingsButton_3.Text = 'Settings';
            app.EnableCheckBox_2 = uicheckbox(app.Stage3Panel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.Best5CheckBox_3 = uicheckbox(app.Stage3Panel);
            app.Best5CheckBox_3.Text = 'Best 5';
            app.ConstraintsTab_2 = uitab(app.TabGroup);
            app.ConstraintsTab_2.Title = 'Constraints';
            app.ForcePanel = uipanel(app.ConstraintsTab_2);
            app.ForcePanel.Title = 'Force';
            app.CriteriaButtonGroup = uibuttongroup(app.ForcePanel);
            app.CriteriaButtonGroup.Title = 'Criteria';
            app.MinimizemaximumForceButton = uiradiobutton(app.CriteriaButtonGroup);
            app.MinimizemaximumForceButton.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton.Value = true;
            app.MinimizesummationofForcesButton = uiradiobutton(app.CriteriaButtonGroup);
            app.MinimizesummationofForcesButton.Text = 'Minimize summation of Forces';
            app.SelectedForcesPanel = uipanel(app.ForcePanel);
            app.SelectedForcesPanel.Title = 'Selected Forces';
            app.Mount1Panel = uipanel(app.SelectedForcesPanel);
            app.Mount1Panel.Title = 'Mount 1';
            app.LocalXCheckBox = uicheckbox(app.Mount1Panel);
            app.LocalXCheckBox.Text = 'Local X';
            app.LocalYCheckBox = uicheckbox(app.Mount1Panel);
            app.LocalYCheckBox.Text = 'Local Y';
            app.LocalZCheckBox = uicheckbox(app.Mount1Panel);
            app.LocalZCheckBox.Text = 'Local Z';
            app.MagnitudeCheckBox = uicheckbox(app.Mount1Panel);
            app.MagnitudeCheckBox.Text = 'Magnitude';
            app.Mount2Panel = uipanel(app.SelectedForcesPanel);
            app.Mount2Panel.Title = 'Mount 2';
            app.LocalXCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.LocalXCheckBox_2.Text = 'Local X';
            app.LocalYCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.LocalYCheckBox_2.Text = 'Local Y';
            app.LocalZCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.LocalZCheckBox_2.Text = 'Local Z';
            app.MagnitudeCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.MagnitudeCheckBox_2.Text = 'Magnitude';
            app.Mount3Panel = uipanel(app.SelectedForcesPanel);
            app.Mount3Panel.Title = 'Mount 3';
            app.LocalXCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.LocalXCheckBox_3.Text = 'Local X';
            app.LocalYCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.LocalYCheckBox_3.Text = 'Local Y';
            app.LocalZCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.LocalZCheckBox_3.Text = 'Local Z';
            app.MagnitudeCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.MagnitudeCheckBox_3.Text = 'Magnitude';
            app.StandardTestsPanel = uipanel(app.ConstraintsTab_2);
            app.StandardTestsPanel.Title = 'Standard Tests';
            app.KEDcriteriaCheckBox = uicheckbox(app.ConstraintsTab_2);
            app.KEDcriteriaCheckBox.Text = 'KED criteria';
            app.KEDcriteriaCheckBox.Value = true;
            app.percentageEditFieldLabel = uilabel(app.ConstraintsTab_2);
            app.percentageEditFieldLabel.Text = 'percentage';
            app.percentageEditField = uieditfield(app.ConstraintsTab_2, 'numeric');
            app.percentageEditField.Value = 85;
            app.UpperandLowerBoundsPanel = uipanel(app.ConstraintsTab_2);
            app.UpperandLowerBoundsPanel.Title = 'Upper and Lower Bounds';
            app.MountsLocationOrientationButton = uibutton(app.UpperandLowerBoundsPanel, 'push');
            app.MountsLocationOrientationButton.Text = {'Mounts Location'; ' & Orientation'};
            app.StaticDeflectionsButton = uibutton(app.UpperandLowerBoundsPanel, 'push');
            app.StaticDeflectionsButton.Text = 'Static Deflections';
            app.NaturalfrequenciesButton = uibutton(app.UpperandLowerBoundsPanel, 'push');
            app.NaturalfrequenciesButton.Text = 'Natural frequencies';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.AddButton = uibutton(app.Tab, 'push');
            app.AddButton.Text = 'Add ';
            app.MoveUpButton = uibutton(app.Tab, 'push');
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.Tab, 'push');
            app.MoveDownButton.Text = 'Move Down';
            app.DeleteButton = uibutton(app.Tab, 'push');
            app.DeleteButton.Text = 'Delete';
            app.AvailableMethodsListBoxLabel = uilabel(app.Tab);
            app.AvailableMethodsListBoxLabel.Text = 'Available Methods';
            app.AvailableMethodsListBox = uilistbox(app.Tab);
            app.AvailableMethodsListBox.Items = {'TRA', 'TF', 'Ar', 'TA'};
            app.AvailableMethodsListBox.Value = 'TRA';
            app.SelectedMethodsListBoxLabel = uilabel(app.Tab);
            app.SelectedMethodsListBoxLabel.Text = 'Selected Methods';
            app.SelectedMethodsListBox = uilistbox(app.Tab);
            app.SelectedMethodsListBox.Items = {};
            app.SelectedMethodsListBox.Value = {};
            app.CloseButton = uibutton(app.OptimizationUIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.OptimizationUIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.STARTButton = uibutton(app.OptimizationUIFigure, 'push');
            app.STARTButton.Text = 'START';
            app.ViewResultsButton = uibutton(app.OptimizationUIFigure, 'push');
            app.ViewResultsButton.Text = 'View Results';
            app.OptimizationLabel_2 = uilabel(app.OptimizationUIFigure);
            app.OptimizationLabel_2.Text = 'Optimization';
            app.OptimizationFileEditFieldLabel = uilabel(app.OptimizationUIFigure);
            app.OptimizationFileEditFieldLabel.Text = 'Optimization File :';
            app.OptimizationFileEditField = uieditfield(app.OptimizationUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_optimization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationUIFigure)
        end
    end
end